classdef SleepScorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Scorematic            matlab.ui.Figure
        SleepScoreButton      matlab.ui.control.Button
        PlotsButton           matlab.ui.control.Button
        runSpectralAnalysis   matlab.ui.control.Button
        text88                matlab.ui.control.Label
        uipanel10             matlab.ui.container.Panel
        text92                matlab.ui.control.Label
        text91                matlab.ui.control.Label
        EEG_HP_cutoff         matlab.ui.control.EditField
        text69                matlab.ui.control.Label
        text68                matlab.ui.control.Label
        EEG_cutoff            matlab.ui.control.EditField
        uipanel6              matlab.ui.container.Panel
        LoadFiles_pushbutton  matlab.ui.control.Button
        cortexCSC             matlab.ui.control.EditField
        text58                matlab.ui.control.Label
        tstampsfile           matlab.ui.control.EditField
        text54                matlab.ui.control.Label
    end

    
    methods (Access = private)
        function [EEG_samples, EEG_timestamps, Fs] = loadEEGData(CscFilename, timestampSections, EEG_Fc, EEG_HP_Fc)
            % This function loads in the CSC amplitude and timestamps for the selected
            % CSC file.  It also automatically down-samples.
            
            waithandle= waitbar(0.2,'Loading the EEG data');pause(0.2);
            lowertimestamp = timestampSections(1,1);
            uppertimestamp = timestampSections(end,2);
            [Timestamps,cscSamplingRate,Samples]=Nlx2MatCSC(CscFilename,[1 0 1 0 1],0,4,[lowertimestamp uppertimestamp]);
            cscSamplingRate = cscSamplingRate(1);
            unfilt_samples=double(Samples(:)');
            exactLow = timestampSections(1,3);
            exactHi = length(unfilt_samples);
            clear Samples
            close(waithandle);
            
            % Precise time stamps should be calculated here:
            waithandle = waitbar(0.6,'Extracting EEG Timestamps...'); pause(0.2);
            [EEG_TIMESTAMPS_temp,EEG_SAMPLES_temp] = generate_timestamps_from_Ncsfiles(Timestamps,unfilt_samples,exactLow, exactHi,[]);
            clear Timestamps unfilt_samples
            close(waithandle);
            
            % Automated down-sampling to down-sample to 1kHz if greater or output an error if below 250Hz:
            if cscSamplingRate > 1050
                DS = (1:1:32);
                DSampSF = cscSamplingRate./DS;
                indSampfactor = find(DSampSF >= 1000);
                Fs = DSampSF(indSampfactor(end));
                sampFactor = DS(indSampfactor(end));
                msgbox({['Recording Sampling Rate:  ' num2str(cscSamplingRate) 'Hz'];...
                    ['Down-Sampled Sampling Rate:  ' num2str(round(Fs)) 'Hz'];...
                    ['Sampling Factor:  ' num2str(sampFactor) '']});
            elseif cscSamplingRate < 250
                msgbox({['Recording Sampling Rate:  ' num2str(cscSamplingRate) 'Hz'];...
                    'This sampling rate is too low to run the phase analyses.';...
                    'Phase-O-Matic will now close.'});
                exit
            else  % In practice, we should be sampling at at least 1kHz, but older files may have been recorded at slower frequencies.
                Fs = cscSamplingRate;
                sampFactor = 1;
                msgbox({['Recording Sampling Rate:  ' num2str(cscSamplingRate) 'Hz'];...
                    'This sampling rate is acceptable to run the phase analyses.'});
            end
            
            % Convert amplitude to uV
            physInput = 2;  %Needed to select proper error box in HeaderADBit.
            ADBit2uV = HeaderADBit(CscFilename, physInput);    %Extract the AD Bit Value.
            EEG_SAMPLES_temp = EEG_SAMPLES_temp * ADBit2uV;   %Convert amplitude of signal from AD Bits to microvolts.
            
            %Design bandpass filter:
            [z, p, k] = ellip(7,1,60, [EEG_HP_Fc EEG_Fc]/(cscSamplingRate/2),'bandpass');
            [sos, g] = zp2sos(z,p,k);
            %Apply bandpass filter to EEG data:
            EEG_SAMPLES_temp = filtfilt(sos,g, EEG_SAMPLES_temp);
            % %  Low pass filter for EEG signals
            % [Blow,Alow] = ellip(7,1,60, EEG_Fc/(cscSamplingRate/2));           % Default setting implements low pass filter with 30hz cutoff
            % EEG_SAMPLES_temp = filter(Blow,Alow,EEG_SAMPLES_temp);
            
            waithandle = waitbar(0.3,'Downsampling EEG Data...'); pause(0.2);
            EEG_TIMESTAMPS_temp = EEG_TIMESTAMPS_temp(1:sampFactor:end);
            EEG_SAMPLES_temp = EEG_SAMPLES_temp(1:sampFactor:end);
            close(waithandle);
            
            %waithandle = waitbar(0.7,'Deshifting EEG Data...'); pause(0.2);
            % EEG_timestamps = [];
            % EEG_samples = [];
            EEG_samples = EEG_SAMPLES_temp;%( floor( ( length(coeffs)/2 )/sampFactor ):(length(EEG_SAMPLES_temp) - floor( (length(coeffs)/2)/sampFactor) ));
            EEG_timestamps = EEG_TIMESTAMPS_temp;%(1:length(EEG_samples));
            % close(waithandle);
            clear EEG_SAMPLES_temp EEG_TIMESTAMPS_temp
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EEG_HP_cutoff
        function EEG_HP_cutoff_Callback(app, event)
            global EEG_HP_Fc
            %disp(app.EEG_HP_cutoff.Value)
            EEG_HP_cutoff = app.EEG_HP_cutoff.Value;   % returns contents of EEG_HP_cutoff as a double
            EEG_HP_cutoffD = sscanf(EEG_HP_cutoff, '%f');
            if isnan(EEG_HP_cutoffD)
                errordlg('Input must be a number','Error');
            end
            if EEG_HP_cutoffD < 0.001
                errordlg('Cutoff frequency must be > 0.001 Hz','Error');
            end
            EEG_HP_Fc = EEG_HP_cutoffD;
            disp(EEG_HP_Fc)
        end

        % Value changed function: EEG_cutoff
        function EEG_cutoff_Callback(app, event)
            global EEG_Fc
            
            EEG_cutoff = app.EEG_cutoff.Value;   % returns contents of EEG_cutoff as a double
            EEG_cutD = sscanf(EEG_cutoff,'%f');
            %disp(class(EEG_cutD))
            %disp(EEG_cutD)
            if isnan(EEG_cutD)
                errordlg('Input must be a number','Error');     %Shoots an error Dialog when 
            end
            if EEG_cutD > 125
                errordlg('Cutoff frequency must be atleast 125Hz','Error'); %shoots an Error Dialog when cutoff frequency is higher than 125Hz
                
            end
            
            EEG_Fc = EEG_cutD;
            %disp(EEG_Fc)
        end

        % Button pushed function: LoadFiles_pushbutton
        function LoadFiles_pushbutton_Callback(app, event)
 
            % Opens file browser to select the EEG file
            working_dir=pwd;
            
            [filename, pathname] = uigetfile({'*.ncs','Neuralynx CSC File (*.ncs)'},'Select the EEG');
            if isequal(filename,0) || isequal(pathname,0)
                uiwait(errordlg('You need to select a file. Please press the button again',...
                    'ERROR','modal'));
                cd(working_dir);
            else
                cd(working_dir);
                cortexCscFile= fullfile(pathname, filename);
                drawnow();
                app.cortexCSC.Value = filename;
                %set(app.cortexCSC.Value,'string',cortexCscFile);
            end
    
            TimeStamp = GenerateTimestamp(cortexCscFile,filename);
            
            drawnow();
            app.tstampsfile.Value = TimeStamp;
            msgbox("autogenerated timestamp file");

        end

        % Button pushed function: runSpectralAnalysis
        function runSpectralAnalysis_Callback(app, event)
            %run standalone Scorer function here, remove all code and use 
            global EEG_Fc EEG_HP_Fc
            
            working_dir = pwd;
            CSCpathname = strcat(working_dir,'\DATA\',app.cortexCSC.Value);
            disp(CSCpathname)
            TSpathname = strcat(working_dir,'\',app.tstampsfile.Value);
            disp(TSpathname)
            % This file uses PERSISTENT variables, and hence we clear them on every new use
            %getting the Filter cutoff data from the user input edit
            %fields.
            disp(app.cortexCSC.Value);
            EEG_HP_Fc = sscanf(app.EEG_HP_cutoff.Value,'%f');
            EEG_Fc = sscanf(app.EEG_cutoff.Value,'%f'); 
            
            %default values for filters
            %EEG_HP_Fc = 0.4Hz
            %EEG_Fc = 70,Hz
            disp(EEG_HP_Fc)
            if EEG_HP_Fc == zeros(0)
                msgbox("no input to cutoff frequencies");   
            end

            % Get UI file names
            
            %disp(class(sleepScoredFile))
            
            StandaloneScorer(CSCpathname,TSpathname,EEG_HP_Fc,EEG_Fc);
            
            
        end

        % Callback function
        function GenerateTimeStampButtonPushed(app, event)
            
        end

        % Button pushed function: PlotsButton
        function PlotsButtonPushed(app, event)
            plottest;
        end

        % Button pushed function: SleepScoreButton
        function SleepScoreButtonPushed(app, event)
            t =date;
            file = strcat(t,'PSD.xlsx');
            DispSleepScore(file);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Scorematic = uifigure('Visible', 'off');
            app.Scorematic.Name = 'PSDvFrequency';
            app.uipanel6 = uipanel(app.Scorematic);
            app.uipanel6.Title = 'File Selection';
            app.text54 = uilabel(app.uipanel6);
            app.text54.Text = 'Time Stamps:';
            app.tstampsfile = uieditfield(app.uipanel6, 'text');
            app.text58 = uilabel(app.uipanel6);
            app.text58.Text = 'EEG:';
            app.cortexCSC = uieditfield(app.uipanel6, 'text');
            app.LoadFiles_pushbutton = uibutton(app.uipanel6, 'push');
            app.LoadFiles_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @LoadFiles_pushbutton_Callback, true);
            app.LoadFiles_pushbutton.Text = 'Load Files';
            app.uipanel10 = uipanel(app.Scorematic);
            app.uipanel10.Title = 'Filters';
            app.EEG_cutoff = uieditfield(app.uipanel10, 'text');
            app.EEG_cutoff.ValueChangedFcn = createCallbackFcn(app, @EEG_cutoff_Callback, true);
            app.EEG_cutoff.Value = '10';
            app.text68 = uilabel(app.uipanel10);
            app.text68.Text = 'Hz';
            app.text69 = uilabel(app.uipanel10);
            app.text69.Text = 'Lowpass:';
            app.EEG_HP_cutoff = uieditfield(app.uipanel10, 'text');
            app.EEG_HP_cutoff.ValueChangedFcn = createCallbackFcn(app, @EEG_HP_cutoff_Callback, true);
            app.EEG_HP_cutoff.Value = '70';
            app.text91 = uilabel(app.uipanel10);
            app.text91.Text = 'Hz';
            app.text92 = uilabel(app.uipanel10);
            app.text92.Text = 'Highpass:';
            app.text88 = uilabel(app.Scorematic);
            app.text88.Text = 'Auto Sleep Scorer';
            app.runSpectralAnalysis = uibutton(app.Scorematic, 'push');
            app.runSpectralAnalysis.ButtonPushedFcn = createCallbackFcn(app, @runSpectralAnalysis_Callback, true);
            app.runSpectralAnalysis.Text = 'Analysis';
            app.PlotsButton = uibutton(app.Scorematic, 'push');
            app.PlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsButtonPushed, true);
            app.PlotsButton.Text = 'Plots';
            app.SleepScoreButton = uibutton(app.Scorematic, 'push');
            app.SleepScoreButton.ButtonPushedFcn = createCallbackFcn(app, @SleepScoreButtonPushed, true);
            app.SleepScoreButton.Text = 'Sleep Score';
            % Show the figure after all components are created
            app.Scorematic.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepScorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Scorematic)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Scorematic)
        end
    end
end